classdef AMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartSequenceButton          matlab.ui.control.Button
        GripforceButton              matlab.ui.control.Button
        TorqueStabilisationButton    matlab.ui.control.Button
        ZeroassistedButton           matlab.ui.control.Button
        WelcometotheexperimentLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        PleaseinputyournameTextAreaLabel  matlab.ui.control.Label
        UsernameTextArea             matlab.ui.control.TextArea
        GripTargetTrackingButton     matlab.ui.control.Button
        HI5TargetTrackingLabel       matlab.ui.control.Label
        FullassistedButton           matlab.ui.control.Button
        SemiassistedButton           matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        PositionTrackingButton       matlab.ui.control.Button
        BrainVisionSysButton         matlab.ui.control.StateButton
        HI5Button                    matlab.ui.control.StateButton
        GripSensorButton             matlab.ui.control.StateButton
        UsernameButton               matlab.ui.control.Button
        GripCalibrationButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSequenceButton
        function StartSequenceButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_HI5_TargetTracking_FullAssisted
        end

        % Button pushed function: ZeroassistedButton
        function ZeroassistedButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_HI5_TargetTracking_ZeroAssisted
        end

        % Button pushed function: GripforceButton
        function GripforceButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip
        end

        % Button pushed function: TorqueStabilisationButton
        function TorqueStabilisationButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
            App_HI5_torque
        end

        % Button pushed function: GripTargetTrackingButton
        function GripTargetTrackingButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip_tracking            
        end

        % Button pushed function: FullassistedButton
        function FullassistedButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_TargetTracking_FullAssisted     
        end

        % Button pushed function: SemiassistedButton
        function SemiassistedButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_TargetTracking_SemiAssisted           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             clear u1 %reading in App from script
             clear u2 %writing in script
             clear u3 % writing in app
             clear u4 %reading in script from app
             u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
             %variables
             Username = char(app.UsernameTextArea.Value);
             save('username','Username'); 
             experiment = 9;%case 9 is save
             %need to initialize and send the below variables in order to
             %meet the required data format
             trial_length = 10;
             countdown = 5;
             numTrials = 2;
             %sending info to the script
             write(u3, strcat(num2str(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(0)), 'string','LocalHost', 1000);
        end

        % Button pushed function: PositionTrackingButton
        function PositionTrackingButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_HI5_position_track
        end

        % Value changed function: BrainVisionSysButton
        function BrainVisionSysButtonValueChanged(app, event)
            value = app.BrainVisionSysButton.Value;
            if value
                udp_rda_test
            elseif ~value
                return
            end
        end

        % Button pushed function: UsernameButton
        function UsernameButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');
        end

        % Button pushed function: GripCalibrationButton
        function GripCalibrationButtonPushed(app, event)
            Username = char(app.UsernameTextArea.Value);
            save('username','Username');            
            App_grip_calib
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartSequenceButton = uibutton(app.UIFigure, 'push');
            app.StartSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @StartSequenceButtonPushed, true);
            app.StartSequenceButton.Text = {'Start Sequence'; ''};
            app.GripforceButton = uibutton(app.UIFigure, 'push');
            app.GripforceButton.ButtonPushedFcn = createCallbackFcn(app, @GripforceButtonPushed, true);
            app.GripforceButton.Text = 'Grip force';
            app.TorqueStabilisationButton = uibutton(app.UIFigure, 'push');
            app.TorqueStabilisationButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueStabilisationButtonPushed, true);
            app.TorqueStabilisationButton.Text = 'Torque Stabilisation';
            app.ZeroassistedButton = uibutton(app.UIFigure, 'push');
            app.ZeroassistedButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroassistedButtonPushed, true);
            app.ZeroassistedButton.Text = 'Zero-assisted';
            app.WelcometotheexperimentLabel = uilabel(app.UIFigure);
            app.WelcometotheexperimentLabel.Text = 'Welcome to the experiment!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'I hope that the EEG and EMG electrodes are not annoying you too much. I''d like to invite you to a sequence of experiments constituting our study on wrist neuromechanics. During the following exercises you should be relaxed, avoid excessive blinking and swallowing. You should try to minimize the muscle contraction and stay calm. When you are ready, hit the Start button!';
            app.PleaseinputyournameTextAreaLabel = uilabel(app.UIFigure);
            app.PleaseinputyournameTextAreaLabel.Text = 'Please input your name:';
            app.UsernameTextArea = uitextarea(app.UIFigure);
            app.GripTargetTrackingButton = uibutton(app.UIFigure, 'push');
            app.GripTargetTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @GripTargetTrackingButtonPushed, true);
            app.GripTargetTrackingButton.Text = 'Grip Target Tracking';
            app.HI5TargetTrackingLabel = uilabel(app.UIFigure);
            app.HI5TargetTrackingLabel.Text = 'HI5 Target Tracking:';
            app.FullassistedButton = uibutton(app.UIFigure, 'push');
            app.FullassistedButton.ButtonPushedFcn = createCallbackFcn(app, @FullassistedButtonPushed, true);
            app.FullassistedButton.Text = 'Full-assisted';
            app.SemiassistedButton = uibutton(app.UIFigure, 'push');
            app.SemiassistedButton.ButtonPushedFcn = createCallbackFcn(app, @SemiassistedButtonPushed, true);
            app.SemiassistedButton.Text = 'Semi-assisted';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Data';
            app.PositionTrackingButton = uibutton(app.UIFigure, 'push');
            app.PositionTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @PositionTrackingButtonPushed, true);
            app.PositionTrackingButton.Text = 'Position Tracking';
            app.BrainVisionSysButton = uibutton(app.UIFigure, 'state');
            app.BrainVisionSysButton.ValueChangedFcn = createCallbackFcn(app, @BrainVisionSysButtonValueChanged, true);
            app.BrainVisionSysButton.Text = 'BrainVisionSys';
            app.HI5Button = uibutton(app.UIFigure, 'state');
            app.HI5Button.Text = 'HI5';
            app.GripSensorButton = uibutton(app.UIFigure, 'state');
            app.GripSensorButton.Text = 'Grip Sensor';
            app.UsernameButton = uibutton(app.UIFigure, 'push');
            app.UsernameButton.ButtonPushedFcn = createCallbackFcn(app, @UsernameButtonPushed, true);
            app.UsernameButton.Text = 'Username';
            app.GripCalibrationButton = uibutton(app.UIFigure, 'push');
            app.GripCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @GripCalibrationButtonPushed, true);
            app.GripCalibrationButton.Text = {'Grip Calibration'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end