classdef App_grip_tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        AngledegEditFieldLabel  matlab.ui.control.Label
        AngledegEditField       matlab.ui.control.NumericEditField
        descriptionLabel        matlab.ui.control.Label
        TaskLabel               matlab.ui.control.Label
        blue                    matlab.ui.control.Image
        red                     matlab.ui.control.Image
        counter                 matlab.ui.control.Label
        trialCounter            matlab.ui.control.Label
        PauseButton             matlab.ui.control.StateButton
        StartPositionButton     matlab.ui.control.Button
        speed                   matlab.ui.control.Label
        StartTrainingButton     matlab.ui.control.Button
        logic                   matlab.ui.control.Button
        logic_2                 matlab.ui.control.Button
        enter                   matlab.ui.control.Image
        rightArrow              matlab.ui.control.Image
        tKey                    matlab.ui.control.Image
        space                   matlab.ui.control.Image
        instructionLabel        matlab.ui.control.Label
        NextButton              matlab.ui.control.Button
        ErrorTextAreaLabel      matlab.ui.control.Label
        ErrorTextArea           matlab.ui.control.TextArea
        Label                   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
        function checkPauseStopStatus(app, experiment)
%             if app.PauseButton.Value
%                 %send trial status 'p' to stop recording/sending data from
%                 %script
%                 %write(u3, strcat(num2str(experiment),'0','0', 'p'), 'string','LocalHost', 5000); 
%                 app.counter.Visible=true;
%                 app.counter.Text='Paused'; 
%                 waitfor(app.PauseButton,'Value',false)
%                 %write(u3, strcat(num2str(experiment),'0','0', '1'), 'string','LocalHost', 5000); 
%                 app.counter.Visible=true;
%                 app.counter.Text='Ready..'; 
%                 pause(2);
%                 app.counter.Text='3';
%                 pause(1);
%                 app.counter.Text='2';
%                 pause(1);
%                 app.counter.Text='1';
%                 pause(1);
%                 app.counter.Visible=false;
%                 app.counter.Text=' ';
%             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        %start with hiding the unnecessary text
        app.space.Visible=true;
        app.enter.Visible=false;
        app.trialCounter.Visible=true;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=true;
        app.NextButton.Visible=false;
        app.logic.Enable = false;
        
        %init udp
        clear u1 %reading in App from script
        clear u2 %writing in script
        clear u3 % writing in app
        clear u4 %reading in script from app
        u1 = udpport("LocalPort",4000); % open udp for FES pw from simulink, clear port if error
        u3 = udpport('LocalPort', 2000);% udp for writing in app to send data to script
        
        %variables
        trial_length = 10;
        numTrials = 2;
        trials = 1;
        
        %initialize
        experiment = 6; %experiment name for transmission
        countdown = 5;
      
        %circle calcs and display
        plot(app.UIAxes,1,1)
        xline(app.UIAxes,0, '--','color','blue')
        framePos=app.UIFigure.Position;
        app.UIAxes.Position = [493 100 1280 500];
        
        
        %App_udp_experiment_function();
        
        while (trials <= numTrials)
            app.trialCounter.Text=['Trial # ' num2str(trials)];
            
            %send trial numebr adn experiment to script
            c = clock;
            clockStart = c(4)*3600+c(5)*60+c(6);
            clockStartPrev = clockStart;
            app.TaskLabel.Text = char(clockStart);
            
            %% this is while loop for 3,2,1,ready counter
            while (clockStart < clockStartPrev + countdown)
                c = clock;
                clockStart = c(4)*3600+c(5)*60+c(6);
                if(clockStart > clockStartPrev + 4)
                    app.counter.Text='1';
                elseif (clockStart > clockStartPrev + 3)
                    app.counter.Text='2';
                elseif (clockStart > clockStartPrev + 2)
                    app.counter.Text='3';
                elseif (clockStart > clockStartPrev)
                    app.counter.Text='Ready..';
                end
                
                pause(0.001)%for some reason this pause is needed for smootheness
            end
    
            %start of the trial marker
            write(u3, strcat(num2str(experiment),uniform_data_trials(numTrials),uniform_data_trials(trial_length),uniform_data_trials(0)), 'string','LocalHost', 1000);
            
            %initiate stuff before the trial loop (clock is the same as in script - no transmision)
            clockCurrent = 0;
            while (clockCurrent < trial_length - 0.02) % -0.02 for delay compensation
                flush(u1);
                script_data = read(u1,32,"string");  % ensure buffer is multiple of number of electrodes used 
                script_data = split(script_data,'');
                f_targ = str2num(cell2mat(strcat(script_data(2),script_data(3),...
                    script_data(4),script_data(5),script_data(6),script_data(7),script_data(8),script_data(9)))); %force target
                f = str2num(cell2mat(strcat(script_data(10),script_data(11),script_data(12),...
                    script_data(13),script_data(14),script_data(15),script_data(16),...
                    script_data(17)))); %force of the human
                Error = str2num(cell2mat(strcat(script_data(18),script_data(19),script_data(20),...
                    script_data(21),script_data(22),script_data(23),script_data(24),...
                    script_data(25)))); %error between target force and human force
                Elapsed_Time = str2num(cell2mat(strcat(script_data(26),script_data(27),script_data(28),...
                    script_data(29),script_data(30),script_data(31),script_data(32),...
                    script_data(33)))); % elapsed time
                
%                 f_targ = str2num(cell2mat(strcat(script_data(2),script_data(3),...
%                     script_data(4),script_data(5),script_data(6)))); %force target
%                 f = str2num(cell2mat(strcat(script_data(7),script_data(8),script_data(9),...
%                     script_data(10),script_data(11)))); %force of the human
%                 Error = str2num(cell2mat(strcat(script_data(12),script_data(13),script_data(14),...
%                     script_data(15),script_data(16)))); %error between target force and human force
%                 Elapsed_Time = str2num(cell2mat(strcat(script_data(17),script_data(18),script_data(19),...
%                     script_data(20),script_data(21)))); % elapsed time

                app.Label.Text = num2str(Elapsed_Time);
                app.ErrorTextArea.Visible=false;
                app.ErrorTextAreaLabel.Visible=false;
                clockCurrent = Elapsed_Time;
                posError = -60*f;
                angDeviation = posError/6400*90;
                sinPos = f_targ;
                
                blueY= 6.8*(sinPos)+335;
                app.blue.Position=[615,blueY,50,50];
                
                angDeviation = floor(angDeviation) + floor( (angDeviation-floor(angDeviation))/0.25) * 0.25;
                redY=-10*angDeviation+100;
                app.red.Position=[625,redY,30,30];
                
                %pause(0.001)
                
                if Elapsed_Time >= trial_length
                    break
                end
            
            end
            
            app.ErrorTextArea.Visible = true;
            app.ErrorTextAreaLabel.Visible = true;
            app.ErrorTextArea.Value = num2str(Error,'%.2f');
            
            trials = trials + 1;
        
        end
        app.trialCounter.Visible=false;
        app.StartButton.Visible=false;
        app.PauseButton.Visible=false;
        app.space.Visible=false;
        app.TaskLabel.Visible=false;
        app.descriptionLabel.Visible=false;
        app.rightArrow.Visible=true;
        app.NextButton.Visible=true;
        app.instructionLabel.Text='Congratulations, you have finished this part of the experiment! To continue, press ';
        app.logic.Enable=true;
        app.trialCounter.Text=' ';
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
        if app.PauseButton.Value
            app.PauseButton.Text = 'Resume (Press Space)';
        else
            app.PauseButton.Text = 'Pause (Press Space)';
        end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'return'
                    if app.logic.Enable
                        StartPositionButtonPushed(app, event);
                    else
                        StartButtonPushed(app, event);
                    end
                case 'rightarrow'
                    if app.logic.Enable
                        NextButtonPushed(app, event);
                    end
                case 'space'
                    if app.PauseButton.Value == false
                        app.PauseButton.Value = true;
                    elseif app.PauseButton.Value == true
                        app.PauseButton.Value = false;
                    end
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            App_torque;
            app.delete;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'background line_e.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AngledegEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegEditFieldLabel.Visible = 'off';
            app.AngledegEditFieldLabel.Text = 'Angle[deg]';
            app.AngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegEditField.Visible = 'off';
            app.descriptionLabel = uilabel(app.UIFigure);
            app.descriptionLabel.Text = 'Set your arm to neutral position and press ';
            app.TaskLabel = uilabel(app.UIFigure);
            app.TaskLabel.Text = 'Follow the target';
            app.blue = uiimage(app.UIFigure);
            app.blue.ImageSource = 'blue dot.png';
            app.red = uiimage(app.UIFigure);
            app.red.ImageSource = 'red_dot.png';
            app.counter = uilabel(app.UIFigure);
            app.counter.Text = '';
            app.trialCounter = uilabel(app.UIFigure);
            app.trialCounter.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = 'Pause';
            app.StartPositionButton = uibutton(app.UIFigure, 'push');
            app.StartPositionButton.Visible = 'off';
            app.StartPositionButton.Text = 'Start';
            app.speed = uilabel(app.UIFigure);
            app.speed.Text = '';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.Visible = 'off';
            app.StartTrainingButton.Text = 'Training';
            app.logic = uibutton(app.UIFigure, 'push');
            app.logic.Enable = 'off';
            app.logic.Visible = 'off';
            app.logic_2 = uibutton(app.UIFigure, 'push');
            app.logic_2.Enable = 'off';
            app.logic_2.Visible = 'off';
            app.enter = uiimage(app.UIFigure);
            app.enter.ImageSource = 'enter-key.png';
            app.rightArrow = uiimage(app.UIFigure);
            app.rightArrow.Visible = 'off';
            app.rightArrow.ImageSource = 'right arrow.png';
            app.tKey = uiimage(app.UIFigure);
            app.tKey.Visible = 'off';
            app.tKey.ImageSource = 'T key.png';
            app.space = uiimage(app.UIFigure);
            app.space.Visible = 'off';
            app.space.ImageSource = 'spacebar-icon-25.jpg';
            app.instructionLabel = uilabel(app.UIFigure);
            app.instructionLabel.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next';
            app.ErrorTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorTextAreaLabel.Visible = 'off';
            app.ErrorTextAreaLabel.Text = 'Error:';
            app.ErrorTextArea = uitextarea(app.UIFigure);
            app.ErrorTextArea.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_grip_tracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end